classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EfficientMultiHopCommunicationusingUAVsUIFigure  matlab.ui.Figure
        Menu                       matlab.ui.container.Menu
        SaveMenu                   matlab.ui.container.Menu
        HelpMenu                   matlab.ui.container.Menu
        ExitMenu                   matlab.ui.container.Menu
        UIAxes                     matlab.ui.control.UIAxes
        SimulationTypeButtonGroup  matlab.ui.container.ButtonGroup
        SingleDroneButton          matlab.ui.control.RadioButton
        MultipleDroneMultiHopChainButton  matlab.ui.control.RadioButton
        MultipleDroneMultiHopEfficientButton  matlab.ui.control.RadioButton
        ParametersPanel            matlab.ui.container.Panel
        RangeSliderLabel           matlab.ui.control.Label
        RangeSlider                matlab.ui.control.Slider
        XcoordinateEditFieldLabel  matlab.ui.control.Label
        XcoordinateEditField       matlab.ui.control.NumericEditField
        YcoordinateEditFieldLabel  matlab.ui.control.Label
        YcoordinateEditField       matlab.ui.control.NumericEditField
        BaseStationLabel           matlab.ui.control.Label
        NumofStopsSpinnerLabel     matlab.ui.control.Label
        NumofStopsSpinner          matlab.ui.control.Spinner
        RandomseedDropDownLabel    matlab.ui.control.Label
        RandomseedDropDown         matlab.ui.control.DropDown
        StartButton                matlab.ui.control.StateButton
        CancelButton               matlab.ui.control.StateButton
        ClearButton                matlab.ui.control.StateButton
        OutputPanel                matlab.ui.container.Panel
        TotalCostLabel             matlab.ui.control.Label
        TimeElapsedLabel           matlab.ui.control.Label
        timeLabel                  matlab.ui.control.Label
        costLabel                  matlab.ui.control.Label
        NumdronesusedLabel         matlab.ui.control.Label
        Label                      matlab.ui.control.Label
    end


    properties (Access = public)
        spinnerValue % Description
        randomSeedValue
        initialXCoordinateValue
        initialYCoordinateValue
        rangeSliderValue
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            
            % Initialisation of varibles from widgets
            NumofStopsSpinnerValueChanged(app, event)
            RandomseedDropDownValueChanged(app, event)
            XcoordinateEditFieldValueChanged(app, event)
            YcoordinateEditFieldValueChanged(app, event)
            RangeSliderValueChanged(app, event)
            
            
            % Initialisation of time and cost
            time = 0;
            cost = 0;
            
            initial_x = app.initialXCoordinateValue;
            initial_y = app.initialYCoordinateValue;
            num_stops = app.spinnerValue;
            random_seed = app.randomSeedValue;
            
            
            % Base Station coordinates
            base_x = initial_x;
            base_y = initial_y;
            
            % Drone intial properties
            drone_range = app.rangeSliderValue;
            drone_x = initial_x;
            drone_y = initial_y;
            id = 1;
            
            % Create objects of Destination, BaseStation and Drone
            baseStation = BaseStation(initial_x, initial_y);
            drone1 = Drone(id, drone_range, drone_x, drone_y);
            
            % Generate random destination points 
            % rng(0,'threefry');
            % rand_x = randi([-10 10],1,10);
            % rand_y = randi([-10 10],1,10);
            % arrayy = {[-13,8],[-14,14],[-16,17],[-19,6],[-21,9],[-23,8],[-28,4],[-26,3],[-26,-6]};
            
            listOfDestinations = {};
            tours = matlabTSPapp(num_stops, base_x, base_y, random_seed, app);
            length(tours)
            
            for i = 1:length(tours)
                dest_x = tours(i, 1);
                dest_y = tours(i, 2);
                destination = Destination(dest_x, dest_y);
                listOfDestinations{i} = destination;
            end
            
            
            listOfDrones = {};
            
            % Add the newly created drone object into the list of drones
            listOfDrones{1} = drone1;
            num_of_drones = 1;
            
            %title('Single Drone Path - TSP (2-OPT)');
            %grid on
            %set(gca,'Xtick',-10 : 1 : 10); %sets the numbered ticks 1 apart
            %set(gca,'Ytick',-10 : 1 : 10); %same as above
            app.UIAxes.XTick = -10:1:10;
            app.UIAxes.YTick = -10:1:10;
            
            hold(app.UIAxes, 'on');
            % Store Drone path graph type, used for legend
            drone_path = plot(app.UIAxes, [drone1.X_coord drone1.X_coord], [drone1.Y_coord drone1.Y_coord], 'r--', 'LineWidth', 2);
            hold(app.UIAxes, 'on');
            for i = 1:length(listOfDestinations)
                % Store Visited node destinations graph type, used for legend
                visited_dest_plot = plot(app.UIAxes, listOfDestinations{i}.X_coord, listOfDestinations{i}.Y_coord, 'd', 'Color', 'black', 'MarkerFaceColor', 'g');
                % Store Visited node destinations graph type, used for legend
                unvisited_dest_plot = plot(app.UIAxes, listOfDestinations{i}.X_coord, listOfDestinations{i}.Y_coord, 'd', 'Color', 'g', 'MarkerFaceColor', 'w');
                hold(app.UIAxes, 'on');
            end 
            % Store Base Station graph type, used for legend
            bs_plot = plot(app.UIAxes, baseStation.X_coord, baseStation.Y_coord, 'o', 'MarkerFaceColor','b');
            
            % Add legend to the graph
            %legend([bs_plot, visited_dest_plot, unvisited_dest_plot, drone_path], {'Base Station', 'Visited Destinations', 'Unvisited Destinations', 'Drone Path'},'AutoUpdate','off', 'Location', 'best');
            
            % Add Base station to the end of the list, for the drone to return
            listOfDestinations{end+1} = baseStation;
            
            % Loops until the drone has reached the destination
            while (~isempty(listOfDestinations)) && ~(drone1.X_coord == listOfDestinations{1}.X_coord && drone1.Y_coord == listOfDestinations{1}.Y_coord)
                % Store previous coordinates, used to plot the graph
                prev_x = drone1.X_coord;
                prev_y = drone1.Y_coord;
                % Move drone
                drone1 = drone1.selectMove(listOfDestinations{1}.X_coord, listOfDestinations{1}.Y_coord);
                cost = cost + 5;
                time = time + 1;
                if (drone1.X_coord == listOfDestinations{1}.X_coord && drone1.Y_coord == listOfDestinations{1}.Y_coord)
                    plot(app.UIAxes, listOfDestinations{1}.X_coord, listOfDestinations{1}.Y_coord, 'd', 'Color', 'black', 'MarkerFaceColor', 'g');
                    hold(app.UIAxes, 'on');
                end
                hold(app.UIAxes, 'on');
                % Connect with a line the previous coordinates with the new coordinates
               % plot(app.UIAxes, [prev_x drone1.X_coord], [prev_y drone1.Y_coord], 'Color', 'w');
                plot(app.UIAxes, [prev_x drone1.X_coord], [prev_y drone1.Y_coord], 'r--', 'LineWidth', 2);
                disp(['Drone coords (', num2str(drone1.X_coord),',' num2str(drone1.Y_coord), ')']);
                pause(0.1)
                if (drone1.X_coord == listOfDestinations{1}.X_coord && drone1.Y_coord == listOfDestinations{1}.Y_coord)
                    if drone1.X_coord == baseStation.X_coord && drone1.Y_coord == baseStation.Y_coord 
                        disp("Returned back to Base Station"); 
                        plot(app.UIAxes, baseStation.X_coord, baseStation.Y_coord, 'o', 'MarkerFaceColor','b');
                        hold(app.UIAxes, 'on');
                    else 
                        disp("Destination reached");
                        hold(app.UIAxes, 'on');
                    end
                    % Remove the first visited destination
                    listOfDestinations = listOfDestinations(2:end);
                end
                hold(app.UIAxes, 'on');
                
                app.costLabel.Text = num2str(cost);
                app.timeLabel.Text = num2str(time);
                app.Label.Text = num2str(num_of_drones);
            end
            
            
            disp(['Total cost: ', num2str(cost)]);
            disp(['Time elapsed: ', num2str(time)]);
            
            
        end

        % Value changed function: NumofStopsSpinner
        function NumofStopsSpinnerValueChanged(app, event)
            app.spinnerValue = app.NumofStopsSpinner.Value;
        end

        % Value changed function: RandomseedDropDown
        function RandomseedDropDownValueChanged(app, event)
            app.randomSeedValue = app.RandomseedDropDown.Value;
            
        end

        % Value changed function: XcoordinateEditField
        function XcoordinateEditFieldValueChanged(app, event)
            app.initialXCoordinateValue = app.XcoordinateEditField.Value;
            
        end

        % Value changed function: YcoordinateEditField
        function YcoordinateEditFieldValueChanged(app, event)
            app.initialYCoordinateValue = app.YcoordinateEditField.Value;
            
        end

        % Value changed function: RangeSlider
        function RangeSliderValueChanged(app, event)
            app.rangeSliderValue = app.RangeSlider.Value;
            
        end

        % Selection changed function: SimulationTypeButtonGroup
        function SimulationTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.SimulationTypeButtonGroup.SelectedObject;
            if (selectedButton.Text == "Single Drone")
                
                
            elseif (selectedButton.Text == "Multiple Drone Multi-Hop (Chain)")
                
                
            elseif (selectedButton.Text == "Multiple Drone Multi-Hop (Efficient)")
                
                
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EfficientMultiHopCommunicationusingUAVsUIFigure = uifigure;
            app.EfficientMultiHopCommunicationusingUAVsUIFigure.Name = 'Efficient Multi Hop Communication using UAVs';
            app.Menu = uimenu(app.EfficientMultiHopCommunicationusingUAVsUIFigure);
            app.Menu.Text = 'Menu';
            app.SaveMenu = uimenu(app.Menu);
            app.SaveMenu.Text = 'Save';
            app.HelpMenu = uimenu(app.Menu);
            app.HelpMenu.Text = 'Help';
            app.ExitMenu = uimenu(app.Menu);
            app.ExitMenu.Text = 'Exit';
            app.UIAxes = uiaxes(app.EfficientMultiHopCommunicationusingUAVsUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XTick = [0 1 2 3 4 5 6 7 8 9 10];
            app.UIAxes.YTick = [0 1 2 3 4 5 6 7 8 9 10];
            app.SimulationTypeButtonGroup = uibuttongroup(app.EfficientMultiHopCommunicationusingUAVsUIFigure);
            app.SimulationTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SimulationTypeButtonGroupSelectionChanged, true);
            app.SimulationTypeButtonGroup.Title = 'Simulation Type';
            app.SingleDroneButton = uiradiobutton(app.SimulationTypeButtonGroup);
            app.SingleDroneButton.Text = 'Single Drone';
            app.SingleDroneButton.Value = true;
            app.MultipleDroneMultiHopChainButton = uiradiobutton(app.SimulationTypeButtonGroup);
            app.MultipleDroneMultiHopChainButton.Text = 'Multiple Drone Multi-Hop (Chain)';
            app.MultipleDroneMultiHopEfficientButton = uiradiobutton(app.SimulationTypeButtonGroup);
            app.MultipleDroneMultiHopEfficientButton.Text = 'Multiple Drone Multi-Hop (Efficient)';
            app.ParametersPanel = uipanel(app.EfficientMultiHopCommunicationusingUAVsUIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.RangeSliderLabel = uilabel(app.ParametersPanel);
            app.RangeSliderLabel.Text = 'Range';
            app.RangeSlider = uislider(app.ParametersPanel);
            app.RangeSlider.Limits = [0 10];
            app.RangeSlider.MajorTickLabels = {'0', '2', '4', '6', '8', '10'};
            app.RangeSlider.ValueChangedFcn = createCallbackFcn(app, @RangeSliderValueChanged, true);
            app.RangeSlider.Value = 2;
            app.XcoordinateEditFieldLabel = uilabel(app.ParametersPanel);
            app.XcoordinateEditFieldLabel.Text = 'X-coordinate';
            app.XcoordinateEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.XcoordinateEditField.ValueChangedFcn = createCallbackFcn(app, @XcoordinateEditFieldValueChanged, true);
            app.YcoordinateEditFieldLabel = uilabel(app.ParametersPanel);
            app.YcoordinateEditFieldLabel.Text = 'Y-coordinate';
            app.YcoordinateEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.YcoordinateEditField.ValueChangedFcn = createCallbackFcn(app, @YcoordinateEditFieldValueChanged, true);
            app.BaseStationLabel = uilabel(app.ParametersPanel);
            app.BaseStationLabel.Text = 'Base Station';
            app.NumofStopsSpinnerLabel = uilabel(app.ParametersPanel);
            app.NumofStopsSpinnerLabel.Text = 'Num. of Stops';
            app.NumofStopsSpinner = uispinner(app.ParametersPanel);
            app.NumofStopsSpinner.Limits = [3 200];
            app.NumofStopsSpinner.ValueDisplayFormat = '%.0f';
            app.NumofStopsSpinner.ValueChangedFcn = createCallbackFcn(app, @NumofStopsSpinnerValueChanged, true);
            app.NumofStopsSpinner.Value = 20;
            app.RandomseedDropDownLabel = uilabel(app.ParametersPanel);
            app.RandomseedDropDownLabel.Text = 'Random-seed';
            app.RandomseedDropDown = uidropdown(app.ParametersPanel);
            app.RandomseedDropDown.Items = {'twister', 'simdTwister', 'combRecursive', 'philox', 'threefry', 'multFibonacci', 'v5uniform', 'v5normal', 'v4'};
            app.RandomseedDropDown.ValueChangedFcn = createCallbackFcn(app, @RandomseedDropDownValueChanged, true);
            app.RandomseedDropDown.Value = 'combRecursive';
            app.StartButton = uibutton(app.EfficientMultiHopCommunicationusingUAVsUIFigure, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.CancelButton = uibutton(app.EfficientMultiHopCommunicationusingUAVsUIFigure, 'state');
            app.CancelButton.Text = 'Cancel';
            app.ClearButton = uibutton(app.EfficientMultiHopCommunicationusingUAVsUIFigure, 'state');
            app.ClearButton.Text = 'Clear';
            app.OutputPanel = uipanel(app.EfficientMultiHopCommunicationusingUAVsUIFigure);
            app.OutputPanel.Title = 'Output';
            app.TotalCostLabel = uilabel(app.OutputPanel);
            app.TotalCostLabel.Text = 'Total Cost:';
            app.TimeElapsedLabel = uilabel(app.OutputPanel);
            app.TimeElapsedLabel.Text = 'Time Elapsed:';
            app.timeLabel = uilabel(app.OutputPanel);
            app.timeLabel.Text = '';
            app.costLabel = uilabel(app.OutputPanel);
            app.costLabel.Text = '';
            app.NumdronesusedLabel = uilabel(app.OutputPanel);
            app.NumdronesusedLabel.Text = 'Num. drones used:';
            app.Label = uilabel(app.OutputPanel);
            app.Label.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EfficientMultiHopCommunicationusingUAVsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EfficientMultiHopCommunicationusingUAVsUIFigure)
        end
    end
end